classdef SequenceControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UseTwoOdorantsCheckBox          matlab.ui.control.CheckBox
        Bank1MFC01EditFieldLabel        matlab.ui.control.Label
        Bank1MFC01EditField             matlab.ui.control.NumericEditField
        Bank2MFC01EditFieldLabel        matlab.ui.control.Label
        Bank2MFC01EditField             matlab.ui.control.NumericEditField
        PrefillTimemsEditFieldLabel     matlab.ui.control.Label
        PrefillTimemsEditField          matlab.ui.control.NumericEditField
        InitializeMachineButton         matlab.ui.control.Button
        LoadSequenceButton_2            matlab.ui.control.Button
        ActivateMachineButton           matlab.ui.control.Button
        StimulusLengthmsEditFieldLabel  matlab.ui.control.Label
        StimulusLengthmsEditField       matlab.ui.control.NumericEditField
        StimulusDelaymsEditFieldLabel   matlab.ui.control.Label
        StimulusDelaymsEditField        matlab.ui.control.NumericEditField
        SequenceMachineControlLabel     matlab.ui.control.Label
        Bank1OdorFirstStimSpinnerLabel  matlab.ui.control.Label
        Bank1OdorFirstStimSpinner       matlab.ui.control.Spinner
        Bank2OdorSecondStimSpinnerLabel  matlab.ui.control.Label
        Bank2OdorSecondStimSpinner      matlab.ui.control.Spinner
        CleanButton                     matlab.ui.control.StateButton
        CleaningCyclesEditFieldLabel    matlab.ui.control.Label
        CleaningCyclesEditField         matlab.ui.control.NumericEditField
        NumberofOdorsSpinnerLabel       matlab.ui.control.Label
        NumberofOdorsSpinner            matlab.ui.control.Spinner
        CleaningLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Paradigm
        MFC1
        MFC2
        FirstStimulus
        SecondStimulus
        PreFillTime
        StimulusLength
        SequenceDelay
        NumOdors
        CleaningCycles
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Set defaults for parameters
            app.Paradigm = 1;
            app.MFC1 = 0;
            app.MFC2 = 0;
            app.FirstStimulus = 1;
            app.SecondStimulus = 1;
            app.PreFillTime = 4000;
            app.StimulusLength = 1000;
            app.SequenceDelay = 1000;
            app.NumOdors = 1;
            app.CleaningCycles = 1;
        end

        % Value changed function: UseTwoOdorantsCheckBox
        function UseTwoOdorantsCheckBoxValueChanged(app, event)
            value = app.UseTwoOdorantsCheckBox.Value;
            app.Paradigm = value;
        end

        % Value changed function: Bank1MFC01EditField
        function Bank1MFC01EditFieldValueChanged(app, event)
            value = app.Bank1MFC01EditField.Value;
            app.MFC1 = value;
        end

        % Value changed function: Bank2MFC01EditField
        function Bank2MFC01EditFieldValueChanged(app, event)
            value = app.Bank2MFC01EditField.Value;
            app.MFC2 = value;
        end

        % Value changed function: PrefillTimemsEditField
        function PrefillTimemsEditFieldValueChanged(app, event)
            value = app.PrefillTimemsEditField.Value;
            app.PreFillTime = value;
        end

        % Value changed function: StimulusLengthmsEditField
        function StimulusLengthmsEditFieldValueChanged(app, event)
            value = app.StimulusLengthmsEditField.Value;
            app.StimulusLength = value;
        end

        % Value changed function: StimulusDelaymsEditField
        function StimulusDelaymsEditFieldValueChanged(app, event)
            value = app.StimulusDelaymsEditField.Value;
            app.SequenceDelay = value;
        end

        % Button pushed function: InitializeMachineButton
        function InitializeMachineButtonPushed(app, event)
            ConnectMsg = InitializeOM;
            f = msgbox(ConnectMsg,"Connection Status");
        end

        % Button pushed function: LoadSequenceButton_2
        function LoadSequenceButton_2Pushed(app, event)
            SendSequence(app.Paradigm, app.MFC1, app.MFC2, app.FirstStimulus, app.SecondStimulus, app.PreFillTime, app.StimulusLength, app.SequenceDelay);
        end

        % Button pushed function: ActivateMachineButton
        function ActivateMachineButtonPushed(app, event)
            ActivateOM;
        end

        % Value changed function: Bank1OdorFirstStimSpinner
        function Bank1OdorFirstStimSpinnerValueChanged(app, event)
            value = app.Bank1OdorFirstStimSpinner.Value;
            app.FirstStimulus = value;
        end

        % Value changed function: Bank2OdorSecondStimSpinner
        function Bank2OdorSecondStimSpinnerValueChanged(app, event)
            value = app.Bank2OdorSecondStimSpinner.Value;
            app.SecondStimulus = value;
        end

        % Value changed function: CleanButton
        function CleanButtonValueChanged(app, event)
            value = app.CleanButton.Value;
            SequenceClean(app.CleaningCycles, app.NumOdors);
        end

        % Value changed function: CleaningCyclesEditField
        function CleaningCyclesEditFieldValueChanged(app, event)
            value = app.CleaningCyclesEditField.Value;
            app.CleaningCycles = value;
        end

        % Value changed function: NumberofOdorsSpinner
        function NumberofOdorsSpinnerValueChanged(app, event)
            value = app.NumberofOdorsSpinner.Value;
            app.NumOdors = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UseTwoOdorantsCheckBox = uicheckbox(app.UIFigure);
            app.UseTwoOdorantsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTwoOdorantsCheckBoxValueChanged, true);
            app.UseTwoOdorantsCheckBox.Text = 'Use Two Odorants?';
            app.UseTwoOdorantsCheckBox.Value = true;
            app.Bank1MFC01EditFieldLabel = uilabel(app.UIFigure);
            app.Bank1MFC01EditFieldLabel.Text = 'Bank 1 MFC (0-1)';
            app.Bank1MFC01EditField = uieditfield(app.UIFigure, 'numeric');
            app.Bank1MFC01EditField.Limits = [0 1];
            app.Bank1MFC01EditField.ValueChangedFcn = createCallbackFcn(app, @Bank1MFC01EditFieldValueChanged, true);
            app.Bank2MFC01EditFieldLabel = uilabel(app.UIFigure);
            app.Bank2MFC01EditFieldLabel.Text = 'Bank 2 MFC (0-1)';
            app.Bank2MFC01EditField = uieditfield(app.UIFigure, 'numeric');
            app.Bank2MFC01EditField.Limits = [0 1];
            app.Bank2MFC01EditField.ValueChangedFcn = createCallbackFcn(app, @Bank2MFC01EditFieldValueChanged, true);
            app.PrefillTimemsEditFieldLabel = uilabel(app.UIFigure);
            app.PrefillTimemsEditFieldLabel.Text = 'Prefill Time (ms)';
            app.PrefillTimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrefillTimemsEditField.Limits = [0 Inf];
            app.PrefillTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @PrefillTimemsEditFieldValueChanged, true);
            app.PrefillTimemsEditField.Value = 4000;
            app.InitializeMachineButton = uibutton(app.UIFigure, 'push');
            app.InitializeMachineButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeMachineButtonPushed, true);
            app.InitializeMachineButton.Text = 'Initialize Machine';
            app.LoadSequenceButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadSequenceButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadSequenceButton_2Pushed, true);
            app.LoadSequenceButton_2.Text = 'Load Sequence';
            app.ActivateMachineButton = uibutton(app.UIFigure, 'push');
            app.ActivateMachineButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateMachineButtonPushed, true);
            app.ActivateMachineButton.Text = 'Activate Machine';
            app.StimulusLengthmsEditFieldLabel = uilabel(app.UIFigure);
            app.StimulusLengthmsEditFieldLabel.Text = 'Stimulus Length (ms)';
            app.StimulusLengthmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimulusLengthmsEditField.Limits = [0 Inf];
            app.StimulusLengthmsEditField.ValueChangedFcn = createCallbackFcn(app, @StimulusLengthmsEditFieldValueChanged, true);
            app.StimulusLengthmsEditField.Value = 2000;
            app.StimulusDelaymsEditFieldLabel = uilabel(app.UIFigure);
            app.StimulusDelaymsEditFieldLabel.Text = 'Stimulus Delay (ms)';
            app.StimulusDelaymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimulusDelaymsEditField.Limits = [0 Inf];
            app.StimulusDelaymsEditField.ValueChangedFcn = createCallbackFcn(app, @StimulusDelaymsEditFieldValueChanged, true);
            app.StimulusDelaymsEditField.Value = 2000;
            app.SequenceMachineControlLabel = uilabel(app.UIFigure);
            app.SequenceMachineControlLabel.Text = 'Sequence Machine Control';
            app.Bank1OdorFirstStimSpinnerLabel = uilabel(app.UIFigure);
            app.Bank1OdorFirstStimSpinnerLabel.Text = 'Bank 1 Odor (First Stim)';
            app.Bank1OdorFirstStimSpinner = uispinner(app.UIFigure);
            app.Bank1OdorFirstStimSpinner.Limits = [1 6];
            app.Bank1OdorFirstStimSpinner.RoundFractionalValues = 'on';
            app.Bank1OdorFirstStimSpinner.ValueDisplayFormat = '%.0f';
            app.Bank1OdorFirstStimSpinner.ValueChangedFcn = createCallbackFcn(app, @Bank1OdorFirstStimSpinnerValueChanged, true);
            app.Bank1OdorFirstStimSpinner.Value = 1;
            app.Bank2OdorSecondStimSpinnerLabel = uilabel(app.UIFigure);
            app.Bank2OdorSecondStimSpinnerLabel.Text = 'Bank 2 Odor (Second Stim)';
            app.Bank2OdorSecondStimSpinner = uispinner(app.UIFigure);
            app.Bank2OdorSecondStimSpinner.Limits = [1 6];
            app.Bank2OdorSecondStimSpinner.RoundFractionalValues = 'on';
            app.Bank2OdorSecondStimSpinner.ValueDisplayFormat = '%.0f';
            app.Bank2OdorSecondStimSpinner.ValueChangedFcn = createCallbackFcn(app, @Bank2OdorSecondStimSpinnerValueChanged, true);
            app.Bank2OdorSecondStimSpinner.Value = 1;
            app.CleanButton = uibutton(app.UIFigure, 'state');
            app.CleanButton.ValueChangedFcn = createCallbackFcn(app, @CleanButtonValueChanged, true);
            app.CleanButton.Text = 'Clean';
            app.CleaningCyclesEditFieldLabel = uilabel(app.UIFigure);
            app.CleaningCyclesEditFieldLabel.Text = 'Cleaning Cycles';
            app.CleaningCyclesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CleaningCyclesEditField.Limits = [0 Inf];
            app.CleaningCyclesEditField.RoundFractionalValues = 'on';
            app.CleaningCyclesEditField.ValueDisplayFormat = '%.0f';
            app.CleaningCyclesEditField.ValueChangedFcn = createCallbackFcn(app, @CleaningCyclesEditFieldValueChanged, true);
            app.CleaningCyclesEditField.Value = 1;
            app.NumberofOdorsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofOdorsSpinnerLabel.Text = 'Number of Odors';
            app.NumberofOdorsSpinner = uispinner(app.UIFigure);
            app.NumberofOdorsSpinner.Limits = [1 6];
            app.NumberofOdorsSpinner.RoundFractionalValues = 'on';
            app.NumberofOdorsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofOdorsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofOdorsSpinnerValueChanged, true);
            app.NumberofOdorsSpinner.Value = 1;
            app.CleaningLabel = uilabel(app.UIFigure);
            app.CleaningLabel.Text = 'Cleaning';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SequenceControl

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end